classdef segment_ChAT < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        LeftPanel                 matlab.ui.container.Panel
        rawImAxes                 matlab.ui.control.UIAxes
        MinEditFieldLabel         matlab.ui.control.Label
        MinEditField              matlab.ui.control.NumericEditField
        MaxEditFieldLabel         matlab.ui.control.Label
        MaxEditField              matlab.ui.control.NumericEditField
        ShowsegmentationCheckBox  matlab.ui.control.CheckBox
        FilenameLabel             matlab.ui.control.Label
        ImInfoLabel               matlab.ui.control.Label
        CenterPanel               matlab.ui.container.Panel
        ROI_manager               matlab.ui.control.ListBox
        ROIsLabel                 matlab.ui.control.Label
        RightPanel                matlab.ui.container.Panel
        IntthresEditFieldLabel    matlab.ui.control.Label
        IntthresEditField         matlab.ui.control.NumericEditField
        AreathresEditFieldLabel   matlab.ui.control.Label
        AreathresEditField        matlab.ui.control.NumericEditField
        BatchModeButton           matlab.ui.control.Button
        LoadimageButton           matlab.ui.control.Button
        SaveButton                matlab.ui.control.Button
        StatusTextLabel           matlab.ui.control.Label
        StatusLabel               matlab.ui.control.Label
        NextImageButton           matlab.ui.control.Button
        NewchannelimageButton     matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end


    properties (Access = public)
        main_im_props = struct; % Description
        segmentation_props = struct('int_thres', 20, 'area_thres', 300); % Description
        batch_mode = struct;
    end
    
    methods (Access = public)
        
        function bg_sub_im = segment_im(app, im)
            filter = [100 100];
            filt_im = medfilt2(im, filter);
            bg_sub_im = im - filt_im;
        end
        
        function background_vals = extract_newchannel_background(app, newchannel_im)
            WINDOW_SIZE = 50; THRES = 20;
            bg_sub_newchannel_im = segment_im(app, newchannel_im);
            newchannel_mask = 0*bg_sub_newchannel_im;
            newchannel_mask(bg_sub_newchannel_im > THRES) = 1;
            neg_newchannel_im = newchannel_im.*(1 - newchannel_mask);
            centroid_data = app.segmentation_props.data_table.Centroid(:, [2, 1]);
            background_vals = zeros(size(centroid_data, 1), 1);
            for c = 1:length(centroid_data)
                row_start = max(ceil(centroid_data(c, 1) - WINDOW_SIZE), 1); row_end = min(floor(centroid_data(c, 1) + WINDOW_SIZE), size(newchannel_im, 1));
                col_start = max(ceil(centroid_data(c, 2) - WINDOW_SIZE), 1); col_end = min(floor(centroid_data(c, 2) + WINDOW_SIZE), size(newchannel_im, 2));
                background_vals(c) = median(nonzeros(neg_newchannel_im(row_start:row_end, col_start:col_end)));
            end
        end
    end
    
    methods (Access = private)
        
        function [labeled_im, num_cells] = update_seg_im(app, viewmode)
            
            thres = app.segmentation_props.int_thres;
            raw_seg_im = app.segmentation_props.raw_seg_im;
            im = app.main_im_props.im;
            mask_im = 0*raw_seg_im;
            mask_im(raw_seg_im> thres) = 1;
            se = strel('square', 5);
            mask_im = imopen(mask_im, se);
            mask_im = bwlabel(mask_im);
            
            props = regionprops(mask_im, im, 'Area', 'MaxIntensity', 'MeanIntensity', 'Centroid');
            areas = [props.Area];
            
            filtered_labels = find(areas > app.segmentation_props.area_thres);
            num_cells = length(filtered_labels);
            
            labeled_im = mask_im;
            labeled_im(~ismember(labeled_im, filtered_labels)) = 0;
            labeled_im = bwlabel(labeled_im);
            app.segmentation_props.data_table = struct2table(props(filtered_labels));
            
            app.segmentation_props.filt_seg_im = labeled_im;
            if strcmp(viewmode, 'noshow')
                %do nothing
            else
                update_main_im(app);
            end
        end
        
        function update_main_im(app)
            norm_im = mat2gray(app.main_im_props.im, double([app.main_im_props.minval, app.main_im_props.maxval]));
            if app.ShowsegmentationCheckBox.Value == 0
                imshow(norm_im, 'Parent', app.rawImAxes);
            else
                imshow(imoverlay(norm_im, app.segmentation_props.filt_seg_im, [1 0 0]), 'Parent', app.rawImAxes)
            end
        end
        
        
        function load_im(app, filename, pathname, viewmode)
            clear_roi(app);
            im = loadStack(fullfile(pathname, filename));
            im_full = im;
            app.main_im_props.size_lims = [1024 2047]; %[5057, 9280]; %[6657, 7680] % big = [5057, 9280] well 1 = [3057, 7280]
            im = im_full(app.main_im_props.size_lims(1):app.main_im_props.size_lims(2), app.main_im_props.size_lims(1):app.main_im_props.size_lims(2));
            app.main_im_props.im = im; %permute(im, [2 1]);
            app.main_im_props.im_filename = fullfile(pathname, filename);
            app.ImInfoLabel.Text = filename;
            if app.MinEditField.Value == 0 && app.MaxEditField.Value == 0
                app.main_im_props.minval = prctile(im(:), 10);
                app.MinEditField.Value = double(app.main_im_props.minval);
                app.main_im_props.maxval = prctile(im(:), 99.9);
                app.MaxEditField.Value = double(app.main_im_props.maxval);
            end
            app.IntthresEditField.Value = app.segmentation_props.int_thres;
            app.AreathresEditField.Value = app.segmentation_props.area_thres;
            app.segmentation_props.raw_seg_im = segment_im(app, app.main_im_props.im);
            
            update_seg_im(app, viewmode);
            app.StatusLabel.Text = 'Image loaded';
        end
        
        function clear_roi(app)
            app.main_im_props.roi_pos_list = [];
            app.ROI_manager.Items = {};
            app.ROI_manager.Value = {};
        end
        
        function results = newchannel_load_im(app, filename, pathname, viewmode)
            newchannel_im = loadStack(fullfile(pathname, filename));
            newchannel_im_full = newchannel_im;
            newchannel_im = newchannel_im_full(app.main_im_props.size_lims(1):app.main_im_props.size_lims(2), app.main_im_props.size_lims(1):app.main_im_props.size_lims(2));
            app.main_im_props.im = newchannel_im;
            if strcmp(viewmode, 'noshow')
                %do nothing
            else
                update_main_im(app);
            end
            
            labeled_im = app.segmentation_props.filt_seg_im;
            props = regionprops(labeled_im, newchannel_im, 'Area', 'MaxIntensity', 'MeanIntensity');
            background_vals = extract_newchannel_background(app, newchannel_im);
            app.segmentation_props.newchannel_data_table = struct2table(props);
            app.segmentation_props.newchannel_data_table.background = background_vals;
        end
        
        function newchannel_data_save(app, outfilename, outpathname)
            dataout = app.segmentation_props.newchannel_data_table;
            save(fullfile(outpathname, outfilename), 'dataout');
            writetable(app.segmentation_props.newchannel_data_table, fullfile(outpathname, [outfilename, '_intensity_data.xlsx']), "FileType", "spreadsheet", 'WriteMode','overwritesheet')
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadimageButton
        function LoadimageButtonPushed(app, event)
            app.StatusLabel.Text = '';
            [filename, pathname] = uigetfile('*.tif');
            load_im(app, filename, pathname, 'show');
            
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            roi_im = 0*app.main_im_props.im;
            value = app.ROI_manager.Value;
            for i = 1:length(app.ROI_manager.Items)
                if ~isempty(find(ismember(value, app.ROI_manager.Items{i})))
                    roi_pos =  app.main_im_props.roi_pos_list(i, :);
                    row_start =  ceil(roi_pos(2)); row_end = floor(roi_pos(2) + roi_pos(4));
                    col_start =  ceil(roi_pos(1)); col_end = floor(roi_pos(1) + roi_pos(3));
                    roi_im(row_start:row_end, col_start:col_end) = 1;
                end
            end
            centroid_im = 0*app.main_im_props.im;
            centroid_data = uint16(app.segmentation_props.data_table.Centroid(:, [2, 1]));
            centroid_im(sub2ind(size(centroid_im), centroid_data(:, 1), centroid_data(:, 2))) = 1:size(centroid_data, 1);
            if max(max(roi_im) > 0)
                centroid_im = centroid_im.*roi_im;
            end
            labels_to_save = centroid_im(find(centroid_im));
            writetable(app.segmentation_props.data_table(labels_to_save, :), [app.main_im_props.im_filename(1:end - 4), '_roi_cells.xlsx'], "FileType", "spreadsheet", 'WriteMode','overwritesheet')
            writetable(app.segmentation_props.data_table(labels_to_save, :), [app.main_im_props.im_filename(1:end - 4), '_all_cells.xlsx'], "FileType", "spreadsheet", 'WriteMode','overwritesheet')
            exportgraphics(app.rawImAxes, [app.main_im_props.im_filename, '_roi_image.png']);
            app.StatusLabel.Text = 'Data saved';
        end

        % Value changed function: MinEditField
        function MinEditFieldValueChanged(app, event)
            app.main_im_props.minval = app.MinEditField.Value;
            app.StatusLabel.Text = '';
            update_main_im(app);
        end

        % Value changed function: MaxEditField
        function MaxEditFieldValueChanged(app, event)
            app.main_im_props.maxval = app.MaxEditField.Value;
            app.StatusLabel.Text = '';
            
            update_main_im(app);
        end

        % Value changed function: IntthresEditField
        function IntthresEditFieldValueChanged(app, event)
            app.StatusLabel.Text = '';
            app.segmentation_props.int_thres = app.IntthresEditField.Value;
            update_seg_im(app, 'show');
        end

        % Value changed function: AreathresEditField
        function AreathresEditFieldValueChanged(app, event)
            app.StatusLabel.Text = '';
            app.segmentation_props.area_thres = app.AreathresEditField.Value;
            update_seg_im(app, 'show');
        end

        % Value changed function: ShowsegmentationCheckBox
        function ShowsegmentationCheckBoxValueChanged(app, event)
            app.StatusLabel.Text = '';
            value = app.ShowsegmentationCheckBox.Value;
            update_seg_im(app, 'show');
            
        end

        % Button pushed function: BatchModeButton
        function BatchModeButtonPushed(app, event)
            app.StatusLabel.Text = '';
            [filenames, pathname] = uigetfile('*CFP*.tif', "MultiSelect","on");
            CFP_index = strfind(filenames{1}, 'CFP');
            
            h = waitbar(0, 'Estimating time remaining ...');
            start = tic;
            for fn = 1:length(filenames)
                load_im(app, filenames{fn}, pathname, 'noshow');
                filename_prefix = filenames{fn}(1:CFP_index - 1);
                search_results = dir(fullfile(pathname, [filename_prefix, '*.tif']));
                if ~isempty(search_results)
                    for k = 1:length(search_results)
                        newfilename = search_results(k).name;
                        newchannel_load_im(app, newfilename, search_results(k).folder, 'noshow');
                        outfilename = [newfilename(1:end-4), '.mat'];
                        outpathname = search_results(k).folder;
                        newchannel_data_save(app, outfilename, outpathname);
                    end
                    if fn == 1
                        elapsed = toc(start);
                    end
                end
                waitbar(fn/length(filenames), h, [num2str(elapsed*(length(filenames) - fn - 1)/60), ' min remaining']);
            end
            
            %             cells_data = cell(length(filenames), 2);
            %
            %                 im = loadStack([pathname, filenames{fn}]);
            %                 app.main_im_props.im_filename = fullfile(pathname, filenames{fn});
            %                 app.main_im_props.im = im;
            %                 app.segmentation_props.raw_seg_im = segment_im(app);
            %                 [~, num_cells] = update_seg_im(app, 'noshow');
            %                 props_data = app.segmentation_props.data_table;
            %                 fn_cell = cell(num_cells, 1);
            %                 [fn_cell{:}] = deal(app.main_im_props.im_filename);
            %                 props_data.Filename = fn_cell;
            %                 if fn == 1
            %                     cell_props_data = props_data;
            %                     elapsed = toc(start);
            %                 else
            %                     cell_props_data = [cell_props_data; props_data];
            %                 end
            %                 SaveButtonPushed(app, []);
            %                 cells_data(fn, :) = {filenames{fn}, num2str(num_cells)};
            %                 waitbar(fn/length(filenames), h, [num2str(elapsed*(length(filenames) - fn - 1)/60), ' min remaining']);
            %             end
            %             close(h);
            %             writetable(table(cells_data), fullfile(pathname, 'cell_num_data.xlsx'), 'FileType','spreadsheet');
            %             writetable(cell_props_data, fullfile(pathname, 'cell_props_data.xlsx'), 'FileType','spreadsheet');
            %
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            if strcmp(key, 'd')
                app.StatusLabel.Text = '';
                roi = drawrectangle(app.rawImAxes);
                if ~isfield(app.main_im_props, 'roi_pos_list')
                    app.main_im_props.roi_pos_list = [];
                end
                app.main_im_props.roi_pos_list(end + 1, 1:4) = roi.Position;
                app.ROI_manager.Items{end + 1} = num2str(size(app.main_im_props.roi_pos_list, 1));
            elseif strcmp(key, 'backspace')
                clear_roi(app)
                delete(app.rawImAxes.Children(1:end - 1));
            end
        end

        % Button down function: UIFigure
        function UIFigureButtonDown(app, event)
            event
        end

        % Value changed function: ROI_manager
        function ROI_managerValueChanged(app, event)
            app.StatusLabel.Text = '';
            value = app.ROI_manager.Value;
            delete(app.rawImAxes.Children(1:end - 1));
            for i = 1:length(app.ROI_manager.Items)
                if ~isempty(find(ismember(value, app.ROI_manager.Items{i})))
                    drawrectangle(app.rawImAxes, 'Position', app.main_im_props.roi_pos_list(i, :));
                end
            end
        end

        % Button pushed function: NextImageButton
        function NextImageButtonPushed(app, event)
            app.StatusLabel.Text = '';
            if app.batch_mode.fn < length(app.batch_mode.filenames)
                app.batch_mode.fn = app.batch_mode.fn + 1;
                load_im(app, app.batch_mode.filenames{app.batch_mode.fn}, app.batch_mode.pathname);
            end
        end

        % Button pushed function: NewchannelimageButton
        function NewchannelimageButtonPushed(app, event)
            app.StatusLabel.Text = '';
            [filename, pathname] = uigetfile('*.tif');
            newchannel_load_im(app, filename, pathname, 'show');
            [outfilename, outpathname] = uiputfile([filename(1:end-4), '.mat']);
            newchannel_data_save(app, outfilename, outpathname);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {480, 480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {571, '1x', 188};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.UIFigure.ButtonDownFcn = createCallbackFcn(app, @UIFigureButtonDown, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.rawImAxes = uiaxes(app.LeftPanel);
            title(app.rawImAxes, '')
            xlabel(app.rawImAxes, '')
            ylabel(app.rawImAxes, '')
            app.rawImAxes.XTick = [];
            app.rawImAxes.YTick = [];
            app.MinEditFieldLabel = uilabel(app.LeftPanel);
            app.MinEditFieldLabel.Text = 'Min';
            app.MinEditField = uieditfield(app.LeftPanel, 'numeric');
            app.MinEditField.ValueChangedFcn = createCallbackFcn(app, @MinEditFieldValueChanged, true);
            app.MaxEditFieldLabel = uilabel(app.LeftPanel);
            app.MaxEditFieldLabel.Text = 'Max';
            app.MaxEditField = uieditfield(app.LeftPanel, 'numeric');
            app.MaxEditField.ValueChangedFcn = createCallbackFcn(app, @MaxEditFieldValueChanged, true);
            app.ShowsegmentationCheckBox = uicheckbox(app.LeftPanel);
            app.ShowsegmentationCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowsegmentationCheckBoxValueChanged, true);
            app.ShowsegmentationCheckBox.Text = 'Show segmentation';
            app.FilenameLabel = uilabel(app.LeftPanel);
            app.FilenameLabel.Text = 'Filename:';
            app.ImInfoLabel = uilabel(app.LeftPanel);
            app.ImInfoLabel.Text = '-';
            app.CenterPanel = uipanel(app.GridLayout);
            app.ROI_manager = uilistbox(app.CenterPanel);
            app.ROI_manager.Items = {};
            app.ROI_manager.Multiselect = 'on';
            app.ROI_manager.ValueChangedFcn = createCallbackFcn(app, @ROI_managerValueChanged, true);
            app.ROI_manager.Value = {};
            app.ROIsLabel = uilabel(app.CenterPanel);
            app.ROIsLabel.Text = 'ROIs';
            app.RightPanel = uipanel(app.GridLayout);
            app.IntthresEditFieldLabel = uilabel(app.RightPanel);
            app.IntthresEditFieldLabel.Text = 'Int. thres';
            app.IntthresEditField = uieditfield(app.RightPanel, 'numeric');
            app.IntthresEditField.ValueChangedFcn = createCallbackFcn(app, @IntthresEditFieldValueChanged, true);
            app.AreathresEditFieldLabel = uilabel(app.RightPanel);
            app.AreathresEditFieldLabel.Text = 'Area thres';
            app.AreathresEditField = uieditfield(app.RightPanel, 'numeric');
            app.AreathresEditField.ValueChangedFcn = createCallbackFcn(app, @AreathresEditFieldValueChanged, true);
            app.BatchModeButton = uibutton(app.RightPanel, 'push');
            app.BatchModeButton.ButtonPushedFcn = createCallbackFcn(app, @BatchModeButtonPushed, true);
            app.BatchModeButton.Text = 'Batch Mode';
            app.LoadimageButton = uibutton(app.RightPanel, 'push');
            app.LoadimageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadimageButtonPushed, true);
            app.LoadimageButton.Text = 'Load image';
            app.SaveButton = uibutton(app.RightPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save data';
            app.StatusTextLabel = uilabel(app.RightPanel);
            app.StatusTextLabel.Text = 'Status:';
            app.StatusLabel = uilabel(app.RightPanel);
            app.StatusLabel.Text = 'Open Image';
            app.NextImageButton = uibutton(app.RightPanel, 'push');
            app.NextImageButton.ButtonPushedFcn = createCallbackFcn(app, @NextImageButtonPushed, true);
            app.NextImageButton.Text = 'Next Image';
            app.NewchannelimageButton = uibutton(app.RightPanel, 'push');
            app.NewchannelimageButton.ButtonPushedFcn = createCallbackFcn(app, @NewchannelimageButtonPushed, true);
            app.NewchannelimageButton.Text = 'New channel image';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = segment_ChAT

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end